classdef OLGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        saveModel               matlab.ui.container.Menu
        loadModel               matlab.ui.container.Menu
        ExitMenu                matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        VideoReEncodeTab        matlab.ui.container.Tab
        inputFolder_parameters  matlab.ui.control.Label
        pickInput_parameters    matlab.ui.control.Button
        inputFolder             matlab.ui.control.Label
        sourceGroup             matlab.ui.container.ButtonGroup
        reencodeFlir            matlab.ui.control.RadioButton
        reencodeGuv             matlab.ui.control.RadioButton
        doneLabel               matlab.ui.control.Label
        outputFolder            matlab.ui.control.Label
        reencodeButton          matlab.ui.control.Button
        pickOutput              matlab.ui.control.Button
        pickInput               matlab.ui.control.Button
        encodeInfo              matlab.ui.control.Label
        DLCAnalysisTab          matlab.ui.container.Tab
        analysisProgressLabel   matlab.ui.control.Label
        performAnalysis         matlab.ui.control.Button
        AnalysisSettingsPanel   matlab.ui.container.Panel
        frontCalcs              matlab.ui.control.CheckBox
        backCalcs               matlab.ui.control.CheckBox
        headCalcs               matlab.ui.control.CheckBox
        wingCalcs               matlab.ui.control.CheckBox
        FrontLegspathLabel      matlab.ui.control.Label
        useFrontLegsCheck       matlab.ui.control.CheckBox
        choosePath_4            matlab.ui.control.Button
        choosePath_3            matlab.ui.control.Button
        BackLegspathLabel       matlab.ui.control.Label
        useBackLegsCheck        matlab.ui.control.CheckBox
        HeadpathLabel           matlab.ui.control.Label
        useHeadCheck            matlab.ui.control.CheckBox
        choosePath_2            matlab.ui.control.Button
        WingspathLabel          matlab.ui.control.Label
        choosePath_1            matlab.ui.control.Button
        useWingsCheck           matlab.ui.control.CheckBox
        AngleCalculationsTab    matlab.ui.container.Tab
        packagingComplete       matlab.ui.control.Label
        packageReady_2          matlab.ui.control.Label
        selectPackagePath       matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        beginPackaging          matlab.ui.control.Button
    end

    
    properties (Access = private)
        % These should contain the input and output folder locations for
        % the re-encode step of OL experiments
        reencodeInputFolder
        reencodeParameterFolder
        reencodeOutputFolder

        % All useful model options to store
        % Set model paths to null on launch
        headPath = 'N/A';
        wingPath = 'N/A';
        frontPath = 'N/A';
        backPath = 'N/A';

        % Set user model choices to 0 on launch
        useWings = 0;
        useHead = 0; 
        useFront = 0;
        useBack = 0;
        
        % File path to video pathways, used in step 3 (.mat packaging)
        videoPathways
    end
    
    methods (Access = private)
        
        function disableAnalysis(app)
            if ~app.useHeadCheck.Value && ~app.useWingsCheck.Value && ...
            ~app.useFrontLegsCheck.Value && ~app.useBackLegsCheck.Value
                app.performAnalysis.Enable = "off";
            end
        end

        function modelList = updateModelList(~, modelList, keyword, filePath, noOfLabels, labels, calculations, name)
            if ~isempty(modelList) % Append to model list if not first model added
                position = length(modelList) + 1;
                modelList(position).keyword = keyword;
                modelList(position).filePath = convertStringsToChars(filePath);
                modelList(position).nooflabels = noOfLabels; %number of body parts being tracked by this model.
                modelList(position).labels = labels;
                modelList(position).calculations = calculations; %Number of variables being calculated : WBA Right and Left, + confidence for each, + LongitudinalAxis (Theta_Angle) and its confidence
                modelList(position).name = name;
            else % If this is the first entry to the model list, initialise it
                modelList(1).keyword = keyword;
                modelList(1).filePath = convertStringsToChars(filePath);
                modelList(1).nooflabels = noOfLabels; %number of body parts being tracked by this model.
                modelList(1).labels = labels;
                modelList(1).calculations = calculations; %Number of variables being calculated : WBA Right and Left, + confidence for each, + LongitudinalAxis (Theta_Angle) and its confidence                
                modelList(1).name = name;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Name the app 
            app.UIFigure.Name = "Open Loop Steps";
        end

        % Button pushed function: pickInput
        function pickInputButtonPushed(app, event)
            % Ask user to locate the inputFolderPath where the videos they wish to encode should be located 
            app.reencodeInputFolder = uigetdir('/home/hoverfly/Documents/', 'Location of Videos to encode');
            app.inputFolder.Text = "Input Folder: " + app.reencodeInputFolder;
            if ~isempty(app.reencodeInputFolder) && ~isempty(app.reencodeOutputFolder)
                app.reencodeButton.Enable = "on";
            end
        end

        % Button pushed function: pickInput_parameters
        function pickInput_parametersButtonPushed(app, event)
            % Ask user to locate the inputFolderPath where the recording
            % parameters are located
            app.reencodeParameterFolder = uigetdir('/home/hoverfly/Documents/', 'Location of video parameters');
            app.inputFolder_parameters.Text = "Input Folder: " + app.reencodeParameterFolder;
            if ~isempty(app.reencodeInputFolder) && ~isempty(app.reencodeOutputFolder)
                app.reencodeButton.Enable = "on";
            end
        end

        % Button pushed function: pickOutput
        function pickOutputButtonPushed(app, event)
            % Ask user to locate the outputFilePath where they wish the Re-encoded versions to be stored
            app.reencodeOutputFolder = uigetdir('/home/hoverfly/Documents/', 'Location to store Re-encoded videos');
            app.outputFolder.Text = "Output Folder: " + app.reencodeOutputFolder;
            if ~isempty(app.reencodeInputFolder) && ~isempty(app.reencodeOutputFolder)
                app.reencodeButton.Enable = "on";
            end
        end

        % Button pushed function: reencodeButton
        function reencodeButtonPushed(app, event)
            % Hide doneLabel, if this is not first run of re-encode
            app.doneLabel.Visible = 'off';

            % Call the re-encode function 
            selectedButton = app.sourceGroup.SelectedObject.Text;

            switch selectedButton
                case "Re-encode Guvcview Videos"
                    reencodeVideo(app.reencodeInputFolder, app.reencodeOutputFolder)
                case "Re-encode Flir Images"
                    reencodeImage(app.reencodeInputFolder, app.reencodeOutputFolder, app.reencodeParameterFolder)
            end
            

            % Show doneLabel, visual feedback for user
            app.doneLabel.Visible = 'on';
        end

        % Button pushed function: choosePath_1
        function choosePath_1ButtonPushed(app, event)
            [file, path] = uigetfile(strcat('', '*.yaml'), "Choose wings model config file");
            filePath = fullfile(path, file);
            if filePath ~= 0 %cancel returns 0
                app.wingPath = filePath;
                app.WingspathLabel.Text = "Wings path: " + filePath;
            end
        end

        % Button pushed function: choosePath_2
        function choosePath_2ButtonPushed(app, event)
            [file, path] = uigetfile(strcat('', '*.yaml'), "Choose head model config file");
            filePath = fullfile(path, file);
            if filePath ~= 0 %cancel returns 0
                app.headPath = filePath;
                app.HeadpathLabel.Text = "Head path: " + filePath;
            end
        end

        % Button pushed function: choosePath_3
        function choosePath_3ButtonPushed(app, event)
            [file, path] = uigetfile(strcat('', '*.yaml'), "Choose hind legs model config file"); 
            filePath = fullfile(path, file);
            if filePath ~= 0 %cancel returns 0
                app.backPath = filePath;
                app.BackLegspathLabel.Text = "BackLegs path: " + filePath;
            end
        end

        % Button pushed function: choosePath_4
        function choosePath_4ButtonPushed(app, event)
            [file, path] = uigetfile(strcat('', '*.yaml'), "Choose front legs model config file");
            filePath = fullfile(path, file);
            if filePath ~= 0 %cancel returns 0
                app.frontPath = filePath;
                app.FrontLegspathLabel.Text = "FrontLegs path: " + filePath;
            end         
        end

        % Value changed function: useWingsCheck
        function useWingsCheckValueChanged(app, event)
            value = app.useWingsCheck.Value;
            if value == 1
                app.choosePath_1.Enable = "on";
                app.performAnalysis.Enable = "on";
            else
                app.choosePath_1.Enable = "off";
                disableAnalysis(app)
            end
            app.useWings = value;
        end

        % Value changed function: useHeadCheck
        function useHeadCheckValueChanged(app, event)
            value = app.useHeadCheck.Value;
            if value == 1
                app.choosePath_2.Enable = "on";
                app.performAnalysis.Enable = "on";
                
            else
                app.choosePath_2.Enable = "off";
                disableAnalysis(app)
            end      
            app.useHead = value;
        end

        % Value changed function: useBackLegsCheck
        function useBackLegsCheckValueChanged(app, event)
            value = app.useBackLegsCheck.Value;
            if value == 1
                app.choosePath_3.Enable = "on";
                app.performAnalysis.Enable = "on";
            else
                app.choosePath_3.Enable = "off";
                disableAnalysis(app)
            end            
            app.useBack = value;
        end

        % Value changed function: useFrontLegsCheck
        function useFrontLegsCheckValueChanged(app, event)
            value = app.useFrontLegsCheck.Value;
            if value == 1
                app.choosePath_4.Enable = "on";
                app.performAnalysis.Enable = "on";
            else
                app.choosePath_4.Enable = "off";
                disableAnalysis(app)
            end           
            app.useFront = value;
        end

        % Button pushed function: performAnalysis
        function performAnalysisButtonPushed(app, event)
            % Model Information (must be accurate)
            % A list of the DLC models each video should be analysed by
            % keyword refers to a Unique ID that can be used to identify each model's .csv files
            modelList = [];

            if app.useWings
                if exist(app.wingPath, "file") && contains(app.wingPath, ".yaml")
                    filePath = app.wingPath;
                else
                    msgbox("Warning: File selected either doesn't exist or is not a valid config file!", "Warning", "warn");
                    return
                end
                [keyword, labels] = dlcNamesFcn(filePath);
                name = 'Wings';
                noOfLabels = length(labels); % Number of body parts being tracked by this model.
                calculations = app.wingCalcs.Value; % Number of variables being calculated : WBA Right and Left, + confidence for each, + LongitudinalAxis (Theta_Angle) and its confidence
                modelList = updateModelList(app, modelList, keyword, filePath, noOfLabels, labels, calculations, name);
            end

            if app.useHead
                if exist(app.headPath, "file") && contains(app.wingPath, ".yaml")
                    filePath = app.headPath;
                else
                    msgbox("Warning: File selected either doesn't exist or is not a valid config file!", "Warning", "warn");
                    return
                end
                [keyword, labels] = dlcNamesFcn(filePath);
                name = 'Head';
                noOfLabels = length(labels); % Number of body parts being tracked by this model.
                calculations = app.headCalcs.Value; %Number of variables being calculated : Slope + confidence
                modelList = updateModelList(app, modelList, keyword, filePath, noOfLabels, labels, calculations, name);
            end

            if app.useBack
                if exist(app.backPath, "file") && contains(app.wingPath, ".yaml")
                    filePath = app.backPath;
                else
                    msgbox("Warning: File selected either doesn't exist or is not a valid config file!", "Warning", "warn");
                    return
                end
                [keyword, labels] = dlcNamesFcn(filePath);
                name = 'Hindlegs';
                noOfLabels = length(labels); % Number of body parts being tracked by this model.
                calculations = app.backCalcs.Value; %Number of variables being calculated : ProximalKnee Angle and Distance, KneeDistal Angle and Distance, + confidence for each, Right and Left
                modelList = updateModelList(app, modelList, keyword, filePath, noOfLabels, labels, calculations, name);
            end

            if app.useFront
                if exist(app.frontPath, "file") && contains(app.wingPath, ".yaml")
                    filePath = app.frontPath;
                else
                    msgbox("Warning: File selected either doesn't exist or is not a valid config file!", "Warning", "warn");
                    return
                end
                [keyword, labels] = dlcNamesFcn(filePath);
                name = 'Frontlegs';
                noOfLabels = length(labels); % Number of body parts being tracked by this model.
                calculations = app.frontCalcs.Value; %Number of variables being calculated : Hinge-Distal Angle and Distance, + confidence, Right and Left
                modelList = updateModelList(app, modelList, keyword, filePath, noOfLabels, labels, calculations, name);
            end

            app.videoPathways = dlcAnalysisFcn(modelList);
            
            app.packageReady_2.Visible = "On";
            app.beginPackaging.Enable = "On";

            app.analysisProgressLabel.Text = ...
                sprintf('Analysis complete, file required for OLStep3 saved as %s', app.videoPathways);
        end

        % Button pushed function: selectPackagePath
        function selectPackagePathButtonPushed(app, event)
            disp('Please select the .mat file representing your video files')

            [file, path] = uigetfile(strcat('', '*.mat'));
            app.videoPathways = fullfile(path, file);

            app.packageReady_2.Visible = "Off";
            app.beginPackaging.Enable = "On";
        end

        % Button pushed function: beginPackaging
        function beginPackagingButtonPushed(app, event)
            app.packagingComplete.Visible = "Off";
            
            % Merge DLC analysis data into a single .mat file and save it
            runCalculationsAndPackage(app.videoPathways)

            app.packagingComplete.Visible = "On";            
        end

        % Menu selected function: saveModel
        function saveModelMenuSelected(app, event)
            % Ask user for file location
            % uiputfile(string): string may be pathname (c:/folder/*.mat)
            [fileName,pathName,~] = uiputfile(strcat('', '*.mat'));
            
            if fileName % If press on cancel no fileName will be given
                % Save model caluclation choices
                appSettings.frontCalcs = app.frontCalcs.Value;
                appSettings.backCalcs = app.backCalcs.Value;
                appSettings.headCalcs = app.headCalcs.Value;
                appSettings.wingCalcs = app.wingCalcs.Value;

                % Save model paths and choices
                appSettings.headPath = app.headPath;
                appSettings.wingPath = app.wingPath;
                appSettings.frontPath = app.frontPath;
                appSettings.backPath = app.backPath;

                appSettings.useWings = app.useWings;
                appSettings.useHead = app.useHead; 
                appSettings.useFront = app.useFront;
                appSettings.useBack = app.useBack;

                % Store location of where file was saved
                appSettings.fileName = fileName;
                appSettings.pathName = pathName;
                appSettings.saved    = 1;
                
                save(strcat(pathName, fileName), 'appSettings');
            end
        end

        % Menu selected function: loadModel
        function loadModelMenuSelected(app, event)
            % Remember to check if not saved!
            % uigetfile(string): string may be pathname (c:/folder/*.mat)
            [fileName,pathName] = uigetfile(strcat('', '*.mat'));
            
            if fileName   % FileName == false if canceled
                
                load(strcat(pathName, fileName), 'appSettings');
                
                if isfield(appSettings, 'saved') % Everything ok
                    % Set / update OL step 2 variables
                    app.performAnalysis.Enable = "off";

                    % Update check boxes with saved variables
                    app.useBackLegsCheck.Value = appSettings.useBack;
                    app.useBack = appSettings.useBack;
                    if appSettings.useBack == 1
                        app.choosePath_3.Enable = "on";
                        app.performAnalysis.Enable = "on";
                    else
                        app.choosePath_3.Enable = "off";
                    end

                    app.useFrontLegsCheck.Value = appSettings.useFront;
                    app.useFront = appSettings.useFront;
                    if appSettings.useBack == 1
                        app.choosePath_4.Enable = "on";
                        app.performAnalysis.Enable = "on";
                    else
                        app.choosePath_4.Enable = "off";
                    end

                    app.useHeadCheck.Value = appSettings.useHead;
                    app.useHead = appSettings.useHead;
                    if appSettings.useBack == 1
                        app.choosePath_2.Enable = "on";
                        app.performAnalysis.Enable = "on";
                    else
                        app.choosePath_2.Enable = "off";
                    end

                    app.useWingsCheck.Value = appSettings.useWings;
                    app.useWings = appSettings.useWings;
                    if appSettings.useBack == 1
                        app.choosePath_1.Enable = "on";
                        app.performAnalysis.Enable = "on";
                    else
                        app.choosePath_1.Enable = "off";
                    end

                    % Set amount of calculations for each model
                    app.frontCalcs.Value = appSettings.frontCalcs;
                    app.backCalcs.Value = appSettings.backCalcs;
                    app.headCalcs.Value = appSettings.headCalcs;
                    app.wingCalcs.Value = appSettings.wingCalcs;

                    % Add model paths to environment
                    app.headPath = appSettings.headPath;
                    app.wingPath = appSettings.wingPath;
                    app.frontPath = appSettings.frontPath;
                    app.backPath = appSettings.backPath;

                    % Update path names to be correct
                    app.WingspathLabel.Text = "Wings path: " + appSettings.wingPath;
                    app.HeadpathLabel.Text = "Head path: " + appSettings.headPath;
                    app.BackLegspathLabel.Text = "BackLegs path: " + appSettings.backPath;
                    app.FrontLegspathLabel.Text = "FrontLegs path: " + appSettings.frontPath;
                else
                    disp('incorrect file');
                end
                
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close(gcf)
        end

        % Selection changed function: sourceGroup
        function sourceGroupSelectionChanged(app, event)
            selectedButton = app.sourceGroup.SelectedObject.Text;

            switch selectedButton
                case "Re-encode Guvcview Videos"
                    app.pickInput.Text = "Select input folder for videos";
                    app.encodeInfo.Text = "This script is used to re-encode the " + ...
                        "video files produced by Guvcview to work properly with Deeplabcut";
                    app.reencodeButton.Text = "Re-encode Videos";
                    app.doneLabel.Text = "Re-encoding of videos is complete! :D";
                    app.inputFolder_parameters.Visible = 'Off';
                    app.pickInput_parameters.Visible = 'Off';
                case "Re-encode Flir Images"
                    app.pickInput.Text = "Select input folder for images";
                    app.encodeInfo.Text = "This script is used to re-encode the " + ...
                        "images produced by Spinview to work properly with Deeplabcut";
                    app.reencodeButton.Text = "Re-encode Images";
                    app.doneLabel.Text = "Re-encoding of images is complete! :D";
                    app.inputFolder_parameters.Visible = 'On';
                    app.pickInput_parameters.Visible = 'On';
            end
        end

        % Callback function
        function TestvisibilityButtonPushed(app, event)
            if app.doneLabel.Visible == "off"
                app.doneLabel.Visible = 'on';
            else
                app.doneLabel.Visible = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.saveModel = uimenu(app.FileMenu);
            app.saveModel.MenuSelectedFcn = createCallbackFcn(app, @saveModelMenuSelected, true);
            app.saveModel.Text = 'Save Settings';
            app.loadModel = uimenu(app.FileMenu);
            app.loadModel.MenuSelectedFcn = createCallbackFcn(app, @loadModelMenuSelected, true);
            app.loadModel.Text = 'Load Settings';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VideoReEncodeTab = uitab(app.TabGroup);
            app.VideoReEncodeTab.Title = 'Video Re-Encode';
            app.encodeInfo = uilabel(app.VideoReEncodeTab);
            app.encodeInfo.Text = 'This script is used to re-encode the video files produced by Guvcview to work properly with Deeplabcut';
            app.pickInput = uibutton(app.VideoReEncodeTab, 'push');
            app.pickInput.ButtonPushedFcn = createCallbackFcn(app, @pickInputButtonPushed, true);
            app.pickInput.Text = 'Select input folder for videos';
            app.pickOutput = uibutton(app.VideoReEncodeTab, 'push');
            app.pickOutput.ButtonPushedFcn = createCallbackFcn(app, @pickOutputButtonPushed, true);
            app.pickOutput.Text = 'Select output folder for re-encoded videos';
            app.reencodeButton = uibutton(app.VideoReEncodeTab, 'push');
            app.reencodeButton.ButtonPushedFcn = createCallbackFcn(app, @reencodeButtonPushed, true);
            app.reencodeButton.Enable = 'off';
            app.reencodeButton.Text = 'Re-encode Videos';
            app.outputFolder = uilabel(app.VideoReEncodeTab);
            app.outputFolder.Text = 'Output Folder: N/A';
            app.doneLabel = uilabel(app.VideoReEncodeTab);
            app.doneLabel.Visible = 'off';
            app.doneLabel.Text = 'Re-encoding of videos is complete! :D';
            app.sourceGroup = uibuttongroup(app.VideoReEncodeTab);
            app.sourceGroup.SelectionChangedFcn = createCallbackFcn(app, @sourceGroupSelectionChanged, true);
            app.reencodeGuv = uiradiobutton(app.sourceGroup);
            app.reencodeGuv.Text = 'Re-encode Guvcview Videos';
            app.reencodeGuv.Value = true;
            app.reencodeFlir = uiradiobutton(app.sourceGroup);
            app.reencodeFlir.Text = 'Re-encode Flir Images';
            app.inputFolder = uilabel(app.VideoReEncodeTab);
            app.inputFolder.Text = 'Input Folder: N/A';
            app.pickInput_parameters = uibutton(app.VideoReEncodeTab, 'push');
            app.pickInput_parameters.ButtonPushedFcn = createCallbackFcn(app, @pickInput_parametersButtonPushed, true);
            app.pickInput_parameters.Visible = 'off';
            app.pickInput_parameters.Text = 'Select input folder for parameters';
            app.inputFolder_parameters = uilabel(app.VideoReEncodeTab);
            app.inputFolder_parameters.Visible = 'off';
            app.inputFolder_parameters.Text = 'Input Folder: N/A';
            app.DLCAnalysisTab = uitab(app.TabGroup);
            app.DLCAnalysisTab.Title = 'DLC Analysis';
            app.AnalysisSettingsPanel = uipanel(app.DLCAnalysisTab);
            app.AnalysisSettingsPanel.Title = 'Analysis Settings';
            app.useWingsCheck = uicheckbox(app.AnalysisSettingsPanel);
            app.useWingsCheck.ValueChangedFcn = createCallbackFcn(app, @useWingsCheckValueChanged, true);
            app.useWingsCheck.Text = 'Use ''Wings'' model';
            app.choosePath_1 = uibutton(app.AnalysisSettingsPanel, 'push');
            app.choosePath_1.ButtonPushedFcn = createCallbackFcn(app, @choosePath_1ButtonPushed, true);
            app.choosePath_1.Enable = 'off';
            app.choosePath_1.Text = 'Choose model path';
            app.WingspathLabel = uilabel(app.AnalysisSettingsPanel);
            app.WingspathLabel.Text = 'Wings path: N/A';
            app.choosePath_2 = uibutton(app.AnalysisSettingsPanel, 'push');
            app.choosePath_2.ButtonPushedFcn = createCallbackFcn(app, @choosePath_2ButtonPushed, true);
            app.choosePath_2.Enable = 'off';
            app.choosePath_2.Text = 'Choose model path';
            app.useHeadCheck = uicheckbox(app.AnalysisSettingsPanel);
            app.useHeadCheck.ValueChangedFcn = createCallbackFcn(app, @useHeadCheckValueChanged, true);
            app.useHeadCheck.Text = 'Use ''Head'' model';
            app.HeadpathLabel = uilabel(app.AnalysisSettingsPanel);
            app.HeadpathLabel.Text = 'Head path: N/A';
            app.useBackLegsCheck = uicheckbox(app.AnalysisSettingsPanel);
            app.useBackLegsCheck.ValueChangedFcn = createCallbackFcn(app, @useBackLegsCheckValueChanged, true);
            app.useBackLegsCheck.Text = 'Use ''BackLegs'' model';
            app.BackLegspathLabel = uilabel(app.AnalysisSettingsPanel);
            app.BackLegspathLabel.Text = 'BackLegs path: N/A';
            app.choosePath_3 = uibutton(app.AnalysisSettingsPanel, 'push');
            app.choosePath_3.ButtonPushedFcn = createCallbackFcn(app, @choosePath_3ButtonPushed, true);
            app.choosePath_3.Enable = 'off';
            app.choosePath_3.Text = 'Choose model path';
            app.choosePath_4 = uibutton(app.AnalysisSettingsPanel, 'push');
            app.choosePath_4.ButtonPushedFcn = createCallbackFcn(app, @choosePath_4ButtonPushed, true);
            app.choosePath_4.Enable = 'off';
            app.choosePath_4.Text = 'Choose model path';
            app.useFrontLegsCheck = uicheckbox(app.AnalysisSettingsPanel);
            app.useFrontLegsCheck.ValueChangedFcn = createCallbackFcn(app, @useFrontLegsCheckValueChanged, true);
            app.useFrontLegsCheck.Text = 'Use ''FrontLegs'' model';
            app.FrontLegspathLabel = uilabel(app.AnalysisSettingsPanel);
            app.FrontLegspathLabel.Text = 'FrontLegs path: N/A';
            app.wingCalcs = uicheckbox(app.AnalysisSettingsPanel);
            app.wingCalcs.Text = 'Run calculations';
            app.wingCalcs.Value = true;
            app.headCalcs = uicheckbox(app.AnalysisSettingsPanel);
            app.headCalcs.Text = 'Run Calculations';
            app.headCalcs.Value = true;
            app.backCalcs = uicheckbox(app.AnalysisSettingsPanel);
            app.backCalcs.Text = 'Run Calculations';
            app.frontCalcs = uicheckbox(app.AnalysisSettingsPanel);
            app.frontCalcs.Text = 'Run calculations';
            app.performAnalysis = uibutton(app.DLCAnalysisTab, 'push');
            app.performAnalysis.ButtonPushedFcn = createCallbackFcn(app, @performAnalysisButtonPushed, true);
            app.performAnalysis.Enable = 'off';
            app.performAnalysis.Text = 'Perform Deep Lab Cut Analysis';
            app.analysisProgressLabel = uilabel(app.DLCAnalysisTab);
            app.analysisProgressLabel.Visible = 'off';
            app.analysisProgressLabel.Text = 'Progress...';
            app.AngleCalculationsTab = uitab(app.TabGroup);
            app.AngleCalculationsTab.Title = 'Angle Calculations';
            app.beginPackaging = uibutton(app.AngleCalculationsTab, 'push');
            app.beginPackaging.ButtonPushedFcn = createCallbackFcn(app, @beginPackagingButtonPushed, true);
            app.beginPackaging.Enable = 'off';
            app.beginPackaging.Text = 'Calculate Angles';
            app.Label = uilabel(app.AngleCalculationsTab);
            app.Label.Text = 'This step will take the raw data, perform the desired angle calculations (as selected by the user), and then package both the raw and calculated data into a single .mat file';
            app.selectPackagePath = uibutton(app.AngleCalculationsTab, 'push');
            app.selectPackagePath.ButtonPushedFcn = createCallbackFcn(app, @selectPackagePathButtonPushed, true);
            app.selectPackagePath.Text = 'Select file containing .csv pathways';
            app.packageReady_2 = uilabel(app.AngleCalculationsTab);
            app.packageReady_2.Visible = 'off';
            app.packageReady_2.Text = '.csv pathways from the last DLC analysis are saved, you can do your calculations now!';
            app.packagingComplete = uilabel(app.AngleCalculationsTab);
            app.packagingComplete.Visible = 'off';
            app.packagingComplete.Text = 'Calculations complete! :D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OLGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end